classdef DatabaseAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DatabaseSelectionTab         matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        SelectDatabaseDropDown       matlab.ui.control.DropDown
        SelectDatabaseDropDownLabel  matlab.ui.control.Label
        GeometryFound                matlab.ui.control.Label
        RefAx                        matlab.ui.control.UIAxes
        ProfilesTab                  matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        Int2                         matlab.ui.control.Label
        Int1                         matlab.ui.control.Label
        RTSRDlbl                     matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        AlphaLabel                   matlab.ui.control.Label
        RadialpositionLabel          matlab.ui.control.Label
        Y25Label                     matlab.ui.control.Label
        y25g                         matlab.ui.control.Label
        X25Label                     matlab.ui.control.Label
        x25g                         matlab.ui.control.Label
        cRLabel                      matlab.ui.control.Label
        cRg                          matlab.ui.control.Label
        RadialpositionGauge          matlab.ui.control.LinearGauge
        AlphaGauge                   matlab.ui.control.NinetyDegreeGauge
        GridLayout3                  matlab.ui.container.GridLayout
        ProfileNumberDropDown        matlab.ui.control.DropDown
        ProfileNumberDropDownLabel   matlab.ui.control.Label
        Target                       matlab.ui.control.UIAxes
        p2Ref                        matlab.ui.control.UIAxes
        RealMeasures                 matlab.ui.control.UIAxes
        Polyroot                     matlab.ui.control.UIAxes
        Intersections                matlab.ui.control.UIAxes
        Final                        matlab.ui.control.UIAxes
        RTSGI                        matlab.ui.control.UIAxes
        RTSRD                        matlab.ui.control.UIAxes
        DistributionsTab             matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        PlotCurrent                  matlab.ui.control.Button
        ClearSIngleAxDropdown        matlab.ui.control.DropDown
        UploadButton                 matlab.ui.control.Button
        ClearAllButton               matlab.ui.control.Button
        SingleClear                  matlab.ui.control.Button
        HoldP3Switch                 matlab.ui.control.Switch
        HoldSwitch_2Label            matlab.ui.control.Label
        y25ax                        matlab.ui.control.UIAxes
        x25ax                        matlab.ui.control.UIAxes
        ChordAx                      matlab.ui.control.UIAxes
        Twistax                      matlab.ui.control.UIAxes
        InterpolatedPolarTab         matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        OOBLabel                     matlab.ui.control.Label
        HoldSwitch                   matlab.ui.control.Switch
        HoldSwitchLabel              matlab.ui.control.Label
        ReynoldsEditField            matlab.ui.control.NumericEditField
        ReynoldsEditFieldLabel       matlab.ui.control.Label
        MachEditField                matlab.ui.control.NumericEditField
        MachEditFieldLabel           matlab.ui.control.Label
        RadiusEditField              matlab.ui.control.NumericEditField
        RadiusEditFieldLabel         matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        PolarCL                      matlab.ui.control.UIAxes
        PolarCD                      matlab.ui.control.UIAxes
        ProfilesComparisonToolTab    matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        PlotcurrentButton            matlab.ui.control.Button
        HoldcomparisonSwitch         matlab.ui.control.Switch
        HoldcomparisonSwitchLabel    matlab.ui.control.Label
        Hyperlink                    matlab.ui.control.Hyperlink
        PCTDropDown                  matlab.ui.control.DropDown
        SelectProfileDropDownLabel   matlab.ui.control.Label
        ClearcomparisonButton        matlab.ui.control.Button
        UploadButton_2               matlab.ui.control.Button
        PCTComparison                matlab.ui.control.UIAxes
        PCTUploaded                  matlab.ui.control.UIAxes
        PCTExtracted                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SavedLegend string % String to save legend
    end
    
    properties (Access = private)
        CurrentDbName string
        TwistLegend string
        ChordLegend string
        x25Legend string
        y25Legend string
        Database struct
        SavedLegend2 string
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        %% User loads plot data and choose where to plot
        function UploadSmtPage2(app,file)

            AxVec=[app.ChordAx app.Twistax app.x25ax app.y25ax];
            
            if not(file)
                return
            end

            if endsWith(file,".txt")
                Data = load(file);
                x = Data(:, 1);
                y = Data(:, 2);
            elseif endsWith(file,".csv")
                Data=readtable(file);
                x=Data.Var1;
                y=Data.Var2;
            elseif endsWith(file,".mat")
                Data=load(file);
                Fields=fieldnames(Data);
                Matrix=Data.(Fields{1});
                NumCols=size(Matrix,2);
            end


                if NumCols>2
                    arr=linspace(1,NumCols,NumCols); %i am a pirate arr
                    spaceList = arrayfun(@num2str, arr, 'UniformOutput', false);
                    [idxx, ~] = listdlg('ListString', spaceList,...
                    'SelectionMode', 'Single', 'PromptString', 'More than 2 columns found, select x-axis column', 'Initialvalue', 1,'Name', 'Confirm','ListSize', [300 300]);
                
                    [idxy, ~] = listdlg('ListString', spaceList,...
                    'SelectionMode', 'Single', 'PromptString', 'Select y-axis column', 'Initialvalue', 1,'Name', 'Confirm','ListSize', [300 300]);

                    x=Matrix(:,idxx);
                    y=Matrix(:,idxy);
                end

            
            %Axis selection
            spaceList = { 'Chord','Twist','x25','z25'}; 
            [idx, ~] = listdlg('ListString', spaceList,...
            'SelectionMode', 'Single', 'PromptString', 'Select plot axis', 'Initialvalue', 1,'Name', 'Confirm');

            %Plotting
            
            SelAx=AxVec(idx);
            plot(SelAx,x,y);
            
            legstr=sprintf("%s",file);

            switch idx
                case 1
                    app.ChordLegend=[app.ChordLegend legstr];
                    legend(SelAx,app.ChordLegend);
                case 2
                    app.TwistLegend=[app.TwistLegend legstr];
                    legend(SelAx,app.TwistLegend);
                case 3
                    app.x25Legend=[app.x25Legend legstr];
                    legend(SelAx,app.x25Legend);
                case 4
                    app.y25Legend=[app.y25Legend legstr];
                    legend(SelAx,app.y25Legend);
            end
            

        end
        
        %% Checking boundaries when input for polar plotting
        function value=checkfieldsOOB(~,MinA,MaxA,MinRe,MaxRe,MinMach,MaxMach,a,Re,Mach,r)
        OutOfBOunds=false;

        if not(a<=MaxA && a>=MinA)
            OutOfBOunds=true;
            fprintf(2,"Alpha out of boundaries of database\n")
        end
    
        if not(Re<=MaxRe && Re>=MinRe)
            OutOfBOunds=true;
            fprintf(2,"Reynolds out of boundaries of database\n")
        end

        if not(Mach<=MaxMach && Mach>=MinMach)
        OutOfBOunds=true;
            fprintf(2,"Mach out of boundaries of database\n")
        end

        if not(r<=1 && r>=0)
            OutOfBOunds=true;
            fprintf(2,"Mach out of boundaries of database\n")
        end

        value=OutOfBOunds;
        end


        %% Update everything in page 3 when db changes
        function ChordTwistChange(app)

            DB=app.Database;

            if isfield(DB,"TR") && isfield(DB,"PlotData")
                IsData=true;
            else
                IsData=false;
            end

                TwistAxs=app.Twistax;
                ChordAxs=app.ChordAx;
                x25Axs=app.x25ax;
                y25Axs=app.y25ax;

                axsArr=[TwistAxs,ChordAxs,x25Axs,y25Axs];

                if app.HoldP3Switch.Value=="On"
                    for Ax=axsArr
                    hold(Ax,"on")
                    end
                else
                    for Ax=axsArr
                        cla(Ax)
                        hold(Ax,"off")
                        app.ChordLegend=[];
                        app.TwistLegend=[];
                        app.x25Legend=[];
                        app.y25Legend=[];
                    end
                end

            if IsData
                plot(ChordAxs,[DB.PlotData(:).RadPos],[DB.PlotData(:).ChordR]);
                xlabel(ChordAxs,"r/R");
                ylabel(ChordAxs,"c/R");
                app.ChordLegend=[app.ChordLegend app.CurrentDbName];
                legend(ChordAxs,app.ChordLegend);

                plot(TwistAxs,[DB.PlotData(:).RadPos],[DB.PlotData(:).alpha]);
                xlabel(TwistAxs,"r/R");
                ylabel(TwistAxs,"alpha°");
                app.TwistLegend=[app.TwistLegend app.CurrentDbName];
                legend(TwistAxs,app.TwistLegend);

                plot(x25Axs,[DB.PlotData(:).RadPos],[DB.PlotData(:).X25]);
                xlabel(x25Axs,"r/R");
                ylabel(x25Axs,"");
                app.x25Legend=[app.x25Legend app.CurrentDbName];
                legend(x25Axs,app.x25Legend);

                plot(y25Axs,[DB.PlotData(:).RadPos],[DB.PlotData(:).Y25]);
                xlabel(y25Axs,"r/R");
                ylabel(y25Axs,"");
                app.y25Legend=[app.x25Legend app.CurrentDbName];
                legend(y25Axs,app.x25Legend);

            else
            end


        end

        %% Update everything in page 2 when a profile is changed
        function ProfileChanged(app,id)
            
            DB=app.Database;

            if isfield(DB,"TR") && isfield(DB,"PlotData")
                IsData=true;
            else
                IsData=false;
            end

            %Pg2 axes
            AxRD=app.RealMeasures;
            AxInt=app.Intersections;
            AxRTSRD=app.RTSRD;
            AxRTSGI=app.RTSGI;
            AxTarget=app.Target;
            AxPolyroot=app.Polyroot;
            AxFinal=app.Final;
            Ax3DRef=app.p2Ref;

            AxesHandleArray=[AxRD,AxInt,AxRTSRD,AxRTSGI,AxTarget,AxPolyroot,AxFinal];

            if IsData %Checking data existence

                for Ax=AxesHandleArray
                    cla(Ax)
                end
                cla(Ax3DRef)

                %Data from db
                RealMeaures=DB.PlotData(id).RealMeasures;
                Inters=DB.PlotData(id).Intersections;
                RTSRDs=DB.PlotData(id).rtsrd;
                RTSGIs=DB.PlotData(id).rtsgi;
                Targets=DB.PlotData(id).target;
                Polyroots=DB.PlotData(id).polyroot;
                X25=DB.PlotData(id).X25;
                Y25=DB.PlotData(id).Y25;
                TR=DB.TR;
                RadPos=DB.PlotData(id).RadPos;
                Alpha=DB.PlotData(id).alpha;

                %gauge data updates
                app.cRg.Text=num2str(DB.PlotData(id).ChordR);
                app.x25g.Text=num2str(DB.PlotData(id).X25);
                app.y25g.Text=num2str(DB.PlotData(id).Y25);
                app.RadialpositionGauge.Value=RadPos;
                app.AlphaGauge.Value=Alpha;
                app.RadialpositionGauge.Tooltip=num2str(RadPos);
                app.AlphaGauge.Tooltip=num2str(Alpha);

                %Plotting time

                for Ax=AxesHandleArray
                    axis(Ax,"equal")
                end


                hold(AxRD,"on");
                plot(AxRD,RealMeaures(:,1),RealMeaures(:,2),".");
                plot(AxRD,X25,Y25,"o");
                hold(AxRD,"off");
                
                if not(isempty(Inters))
                    hold(AxInt,"on");
                    plot(AxInt,Inters(:,1),Inters(:,2),".");      
                    axis(AxInt,"equal");
                    hold(AxInt,"off");

                    hold(AxRTSGI,"on");
                    plot(AxRTSGI,RTSGIs(:,1),RTSGIs(:,2),".");      
                    xlim(AxRTSGI,[-0.1 1.1])
                    ylim(AxRTSGI,[-0.2 0.2])
                    hold(AxRTSGI,"off");
                    app.Int1.Text="";
                    app.Int2.Text="";
                else
                    app.Int1.Text="Not avaliable";
                    app.Int2.Text="Not avaliable";
                end

                if not(isempty(RTSRDs))
                hold(AxRTSRD,"on");
                plot(AxRTSRD,RTSRDs(:,1),RTSRDs(:,2),".");      
                xlim(AxRTSRD,[-0.1 1.1])
                ylim(AxRTSRD,[-0.2 0.2])
                hold(AxRTSRD,"off");
                app.RTSRDlbl.Text="";
                else
                    app.RTSRDlbl.Text="Not needed";
                end

                hold(AxTarget,"on");
                plot(AxTarget,Targets(:,1),Targets(:,2));      
                xlim(AxTarget,[-0.1 1.1])
                ylim(AxTarget,[-0.2 0.2])
                hold(AxTarget,"off");

                hold(AxPolyroot,"on");
                %Upper and lower split, with interp points from target
                plot(AxPolyroot,Targets(:,1),Targets(:,2),".");  
                xlim(AxPolyroot,[-0.1 1.1])
                ylim(AxPolyroot,[-0.2 0.2])
                
                Le=find(Polyroots==[0,0],1);
                UpPlrts=Polyroots(1:Le,:);
                LwrPlrts=Polyroots(Le:end,:);
                
                plot(AxPolyroot,LwrPlrts(:,1),LwrPlrts(:,2));
                plot(AxPolyroot,UpPlrts(:,1),UpPlrts(:,2));  
                hold(AxPolyroot,"off");

                hold(AxFinal,"on");
                plot(AxFinal,Polyroots(:,1),Polyroots(:,2));      
                xlim(AxFinal,[-0.1 1.1])
                ylim(AxFinal,[-0.2 0.2])
                hold(AxFinal,"off");

                %3D reference
                Output=StlGeometryAutomaticFix(TR);
                XX=Output.Points;
                xx=XX(:,1);
                yy=XX(:,2);
                zz=XX(:,3);
                xpmin=min(xx);
                xpmax=max(xx);
                ypmin=min(yy);
                ypmax=max(yy);
                [xp,yp] = meshgrid([xpmin xpmax],[ypmin ypmax]);
                plot3(Ax3DRef,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
                view(Ax3DRef,85,45)
                hold(Ax3DRef,"on")
                axis(Ax3DRef,"equal")
                plot3(Ax3DRef,0,0,0,"o","Color","red","MarkerSize",10)
                zmax=max(zz);
                z=RadPos*zmax;
                zp = 0.*xp + 0.*xp + z;
                surf(Ax3DRef,-xp,zp,yp,"FaceColor","yellow");
                hold(Ax3DRef,"off")


            else %no data
                for Ax=AxesHandleArray
                    cla(Ax)
                    cla(Ax3DRef)
                end
            end


        end


        %Updating all data when a different db is selected
        function DbChanged(app,Name)
            
            Name=string(Name);
            app.CurrentDbName=Name;

            load(Name,"Database")
            app.Database=Database; %#ok<ADPROPLC>

            if isfield(app.Database,"TR") && isfield(app.Database,"PlotData")
                IsData=true;
            else
                IsData=false;
            end


            %Getting all axes
            %Pg 1
            Ax1=app.RefAx;


            %% Plotting data
            
            
            if IsData
                app.GeometryFound.Text="";
                
                TR=app.Database.TR;
                Output=StlGeometryAutomaticFix(TR);
                XX=Output.Points;
                xx=XX(:,1);
                yy=XX(:,2);
                zz=XX(:,3);
                
                cla(Ax1)
                plot3(Ax1,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
                hold(Ax1,"on")
                plot3(Ax1,0,0,0,"o","Color","red","MarkerSize",10)
                axis(Ax1,"equal")
                hold(Ax1,"off")

                %Updating profiles dropdown
                [~,ProfilesNumber]=size(app.Database.PlotData);
                Profiles=linspace(1,ProfilesNumber,ProfilesNumber);
                Profiles = cellstr(num2str(Profiles(:)));
                app.ProfileNumberDropDown.Items=Profiles;
                app.PCTDropDown.Items=Profiles;

                %Updating Profiles
                ProfileChanged(app,1);
                
            else
                Ax1=app.RefAx;
                cla(Ax1)
                app.GeometryFound.Text="Geometry data not found";
            end


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            %Adding crucial directories to path
            addpath("Functions\","STL\")

            %setting databases in dropdown list
            DbList=dir("*.mat");
            DbList=struct2cell(DbList);
            app.SelectDatabaseDropDown.Items=DbList(1,:);

            %Making equal axis in pct
            axVex=[app.PCTComparison,app.PCTUploaded,app.PCTExtracted];
            for ax=axVex
                axis(ax,"equal")
                xlim(ax,[-0.1 1.1])
                ylim(ax,[-0.3 0.3])
            end
            
            %Printing smt on graphic 1 so it isnt empty
            DbChanged(app,DbList(1,1));

        end

        % Value changed function: SelectDatabaseDropDown
        function SelectDatabaseDropDownValueChanged(app, event)
            value = app.SelectDatabaseDropDown.Value;
            DbChanged(app,value)
        end

        % Value changed function: ProfileNumberDropDown
        function ProfileNumberDropDownValueChanged(app, event)
            id = app.ProfileNumberDropDown.Value;
            NumId=int64(str2double(id));
            %IdInt = double(id) - double('0');
            %IdInt=IdInt(end);
            ProfileChanged(app,NumId)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            Clax=app.PolarCL;
            Cdax=app.PolarCD;

            if app.HoldSwitch.Value=="On"
                hold(Clax,"on")
                hold(Cdax,"on")
            else
                hold(Clax,"off")
                hold(Cdax,"off")
                app.SavedLegend=[];
            end
            

            resolution=10;

            DB=app.Database;

            MaxAlpha=max(DB.grid.a);
            MinAlpha=min(DB.grid.a);
            MaxRe=max(DB.grid.Re);
            MinRe=min(DB.grid.Re);
            MaxM=max(DB.grid.Mach);
            MinM=min(DB.grid.Mach);
            
            Re=app.ReynoldsEditField.Value;
            Mach=app.MachEditField.Value;
            r=app.RadiusEditField.Value;
            
            outofboundaries=checkfieldsOOB(app,MinAlpha,MaxAlpha,MinRe,MaxRe,MinM,MaxM,(MaxAlpha+MinAlpha)/2,Re,Mach,r);
            
            if not(outofboundaries)
            
                Cls=zeros(1,resolution);
                Cds=zeros(1,resolution);
                k=1;
                alphas=linspace(MinAlpha,MaxAlpha,resolution);

                for a=alphas
                    Values=GetPolar(r,a,Re,Mach,DB);
                    Cls(k)=Values.CL;
                    Cds(k)=Values.CD;
                    k=k+1;
                end

                legend_string=sprintf("%s -- R=%.2f Re=%.0f Mach=%.2f",app.CurrentDbName,r,Re,Mach);
                app.SavedLegend=[app.SavedLegend,legend_string];

                plot(Clax,alphas,Cls)
                legend(Clax,app.SavedLegend)

                plot(Cdax,alphas,Cds)
                legend(Cdax,app.SavedLegend)

                app.OOBLabel.Text="";
            else
                app.OOBLabel.Text=sprintf("Values out of boundaries. Allowed values:\nAlpha: %d to %d\nMach: %d to %d\nRe: %d to %d",MinAlpha,MaxAlpha,MinM,MaxM,MinRe,MaxRe);
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            Clax=app.PolarCL;
            Cdax=app.PolarCD;
            cla(Cdax);
            cla(Clax);
            app.SavedLegend=[];
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            AxVec=[app.ChordAx app.Twistax app.x25ax app.y25ax];
            app.TwistLegend=[];
            app.ChordLegend=[];
            app.x25Legend=[];
            app.y25Legend=[];
            for a=AxVec
                cla(a)
            end
                
        end

        % Button pushed function: PlotCurrent
        function PlotCurrentButtonPushed(app, event)
            ChordTwistChange(app)
        end

        % Button pushed function: SingleClear
        function SingleClearButtonPushed(app, event)
            c=app.ChordAx;
            t=app.Twistax;
            x=app.x25ax;
            y=app.y25ax;
            
            Choice=app.ClearSIngleAxDropdown.Value;
            switch Choice
                case "Twist"
                    sel=t;
                    app.TwistLegend=[];
                case "Chord"
                    sel=c;
                    app.ChordLegend=[];
                case "x25"
                    sel=x;
                    app.x25Legend=[];
                case "z25"
                    sel=y;
                    app.y25Legend=[];
            end
            cla(sel)
            
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            FileTypeFilter={'*.csv';'*.mat';'*.txt'};
            file=uiputfile(FileTypeFilter);
            UploadSmtPage2(app,file)
        end

        % Value changed function: PCTDropDown
        function PCTDropDownValueChanged(app, event)
            id = app.PCTDropDown.Value;
            NumId=int64(str2double(id));
            Matrix=app.Database.PlotData(NumId).polyroot;
            AxE=app.PCTExtracted;
            AxC=app.PCTComparison;


            plot(AxE,Matrix(:,1),Matrix(:,2));
            plot(AxC,Matrix(:,1),Matrix(:,2));
            Legstring=sprintf("Profilo %d",NumId);
            if app.HoldcomparisonSwitch.Value=="Off"
                app.SavedLegend2=[];
            end
            app.SavedLegend2=[app.SavedLegend2,Legstring];
            legend(AxC,app.SavedLegend2);
        end

        % Value changed function: HoldcomparisonSwitch
        function HoldcomparisonSwitchValueChanged(app, event)
            value = app.HoldcomparisonSwitch.Value;
            AxC=app.PCTComparison;
            if value=="On"
                hold(AxC,"on")
            else
                hold(AxC,"off");
            end
        end

        % Button pushed function: PlotcurrentButton
        function PlotcurrentButtonPushed(app, event)
            ax=app.PCTUploaded;
            cla(ax)
        end

        % Button pushed function: ClearcomparisonButton
        function ClearcomparisonButtonPushed(app, event)
            ax=app.PCTComparison;
            cla(ax)
            app.SavedLegend2=[];
        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
            AxU=app.PCTUploaded;
            AxC=app.PCTComparison;
            FileTypeFilter={'*.csv';'*.mat';'*.txt'};
            file=uiputfile(FileTypeFilter);
            if endsWith(file,".txt")
                Data = load(file);
                x = Data(:, 1);
                y = Data(:, 2);
            end

            if endsWith(file,".csv")
                Data=readtable(file);
                x=Data.Var1;
                y=Data.Var2;
            end

            plot(AxC,x,y,"*")
            plot(AxU,x,y,"*")
            LegStr=sprintf("%s",file);
            app.SavedLegend2=[app.SavedLegend2, LegStr];
            legend(AxC,app.SavedLegend2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSelectionTab = uitab(app.TabGroup);
            app.DatabaseSelectionTab.Title = 'Database Selection';
            app.GridLayout2 = uigridlayout(app.DatabaseSelectionTab);
            app.RefAx = uiaxes(app.GridLayout2);
            title(app.RefAx, '3D Reference')
            xlabel(app.RefAx, 'X')
            ylabel(app.RefAx, 'Y')
            zlabel(app.RefAx, 'Z')
            app.GeometryFound = uilabel(app.GridLayout2);
            app.GeometryFound.Text = '';
            app.SelectDatabaseDropDownLabel = uilabel(app.GridLayout2);
            app.SelectDatabaseDropDownLabel.Text = 'Select Database';
            app.SelectDatabaseDropDown = uidropdown(app.GridLayout2);
            app.SelectDatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDatabaseDropDownValueChanged, true);
            app.ProfilesTab = uitab(app.TabGroup);
            app.ProfilesTab.Title = 'Profiles';
            app.GridLayout = uigridlayout(app.ProfilesTab);
            app.RTSRD = uiaxes(app.GridLayout);
            title(app.RTSRD, 'Rotated translated scaled real measures')
            xlabel(app.RTSRD, 'X')
            ylabel(app.RTSRD, 'Z')
            zlabel(app.RTSRD, 'Z')
            app.RTSGI = uiaxes(app.GridLayout);
            title(app.RTSGI, 'Rotated translated scaled generated intersections')
            xlabel(app.RTSGI, 'X')
            ylabel(app.RTSGI, 'Z')
            zlabel(app.RTSGI, 'Z')
            app.Final = uiaxes(app.GridLayout);
            title(app.Final, 'Final result')
            xlabel(app.Final, 'X')
            ylabel(app.Final, 'Z')
            zlabel(app.Final, 'Z')
            app.Intersections = uiaxes(app.GridLayout);
            title(app.Intersections, 'Intersections')
            xlabel(app.Intersections, 'X')
            ylabel(app.Intersections, 'Z')
            zlabel(app.Intersections, 'Z')
            app.Polyroot = uiaxes(app.GridLayout);
            title(app.Polyroot, 'Polyroot')
            xlabel(app.Polyroot, 'X')
            ylabel(app.Polyroot, 'Z')
            zlabel(app.Polyroot, 'Z')
            app.RealMeasures = uiaxes(app.GridLayout);
            title(app.RealMeasures, 'Real Measures')
            xlabel(app.RealMeasures, 'X')
            ylabel(app.RealMeasures, 'Z')
            zlabel(app.RealMeasures, 'Z')
            app.p2Ref = uiaxes(app.GridLayout);
            title(app.p2Ref, '3D Reference')
            xlabel(app.p2Ref, 'X')
            ylabel(app.p2Ref, 'Y')
            zlabel(app.p2Ref, 'Z')
            app.Target = uiaxes(app.GridLayout);
            title(app.Target, 'Target Profile')
            xlabel(app.Target, 'X')
            ylabel(app.Target, 'Z')
            zlabel(app.Target, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ProfileNumberDropDownLabel = uilabel(app.GridLayout3);
            app.ProfileNumberDropDownLabel.Text = 'Profile Number';
            app.ProfileNumberDropDown = uidropdown(app.GridLayout3);
            app.ProfileNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileNumberDropDownValueChanged, true);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.AlphaGauge = uigauge(app.GridLayout5, 'ninetydegree');
            app.AlphaGauge.Limits = [0 90];
            app.RadialpositionGauge = uigauge(app.GridLayout5, 'linear');
            app.RadialpositionGauge.Limits = [0 1];
            app.cRg = uilabel(app.GridLayout5);
            app.cRg.Text = '';
            app.cRLabel = uilabel(app.GridLayout5);
            app.cRLabel.Text = 'c/R';
            app.x25g = uilabel(app.GridLayout5);
            app.x25g.Text = '';
            app.X25Label = uilabel(app.GridLayout5);
            app.X25Label.Text = 'X25';
            app.y25g = uilabel(app.GridLayout5);
            app.y25g.Text = '';
            app.Y25Label = uilabel(app.GridLayout5);
            app.Y25Label.Text = 'Y25';
            app.RadialpositionLabel = uilabel(app.GridLayout5);
            app.RadialpositionLabel.Text = 'Radial position';
            app.AlphaLabel = uilabel(app.GridLayout5);
            app.AlphaLabel.Text = 'Alpha°';
            app.RTSRDlbl = uilabel(app.GridLayout);
            app.RTSRDlbl.Text = '';
            app.Int1 = uilabel(app.GridLayout);
            app.Int1.Text = '';
            app.Int2 = uilabel(app.GridLayout);
            app.Int2.Text = '';
            app.DistributionsTab = uitab(app.TabGroup);
            app.DistributionsTab.Title = 'Distributions';
            app.GridLayout6 = uigridlayout(app.DistributionsTab);
            app.Twistax = uiaxes(app.GridLayout6);
            title(app.Twistax, 'Twist')
            xlabel(app.Twistax, 'r/R')
            ylabel(app.Twistax, 'Alpha [deg]')
            zlabel(app.Twistax, 'Z')
            app.ChordAx = uiaxes(app.GridLayout6);
            title(app.ChordAx, 'Chord')
            xlabel(app.ChordAx, 'r/R')
            ylabel(app.ChordAx, 'c/R')
            zlabel(app.ChordAx, 'Z')
            app.x25ax = uiaxes(app.GridLayout6);
            title(app.x25ax, 'x25')
            xlabel(app.x25ax, 'r/R')
            zlabel(app.x25ax, 'Z')
            app.y25ax = uiaxes(app.GridLayout6);
            title(app.y25ax, 'z25')
            xlabel(app.y25ax, 'r/R')
            zlabel(app.y25ax, 'Z')
            app.HoldSwitch_2Label = uilabel(app.GridLayout6);
            app.HoldSwitch_2Label.Text = 'Hold';
            app.HoldP3Switch = uiswitch(app.GridLayout6, 'slider');
            app.SingleClear = uibutton(app.GridLayout6, 'push');
            app.SingleClear.ButtonPushedFcn = createCallbackFcn(app, @SingleClearButtonPushed, true);
            app.SingleClear.Text = 'Clear specific';
            app.ClearAllButton = uibutton(app.GridLayout6, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.UploadButton = uibutton(app.GridLayout6, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ClearSIngleAxDropdown = uidropdown(app.GridLayout6);
            app.ClearSIngleAxDropdown.Items = {'Chord', 'Twist', 'x25', 'z25'};
            app.ClearSIngleAxDropdown.Value = 'Chord';
            app.PlotCurrent = uibutton(app.GridLayout6, 'push');
            app.PlotCurrent.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentButtonPushed, true);
            app.PlotCurrent.Text = 'Plot current data';
            app.InterpolatedPolarTab = uitab(app.TabGroup);
            app.InterpolatedPolarTab.Title = 'Interpolated Polar';
            app.GridLayout7 = uigridlayout(app.InterpolatedPolarTab);
            app.PolarCD = uiaxes(app.GridLayout7);
            title(app.PolarCD, 'C_d')
            xlabel(app.PolarCD, 'Alpha [deg]')
            ylabel(app.PolarCD, 'C_d')
            zlabel(app.PolarCD, 'Z')
            app.PolarCL = uiaxes(app.GridLayout7);
            title(app.PolarCL, 'C_l')
            xlabel(app.PolarCL, 'Alpha [deg]')
            ylabel(app.PolarCL, 'C_l')
            zlabel(app.PolarCL, 'Z')
            app.PlotButton = uibutton(app.GridLayout7, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.GridLayout7, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RadiusEditFieldLabel = uilabel(app.GridLayout7);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.GridLayout7, 'numeric');
            app.RadiusEditField.Value = 0.1;
            app.MachEditFieldLabel = uilabel(app.GridLayout7);
            app.MachEditFieldLabel.Text = 'Mach';
            app.MachEditField = uieditfield(app.GridLayout7, 'numeric');
            app.MachEditField.Value = 0.1;
            app.ReynoldsEditFieldLabel = uilabel(app.GridLayout7);
            app.ReynoldsEditFieldLabel.Text = 'Reynolds';
            app.ReynoldsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ReynoldsEditField.Value = 23000;
            app.HoldSwitchLabel = uilabel(app.GridLayout7);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.GridLayout7, 'slider');
            app.OOBLabel = uilabel(app.GridLayout7);
            app.OOBLabel.Text = '';
            app.ProfilesComparisonToolTab = uitab(app.TabGroup);
            app.ProfilesComparisonToolTab.Title = 'Profiles Comparison Tool';
            app.GridLayout8 = uigridlayout(app.ProfilesComparisonToolTab);
            app.PCTExtracted = uiaxes(app.GridLayout8);
            title(app.PCTExtracted, 'Extracted')
            xlabel(app.PCTExtracted, 'X')
            ylabel(app.PCTExtracted, 'Z')
            zlabel(app.PCTExtracted, 'Z')
            app.PCTUploaded = uiaxes(app.GridLayout8);
            title(app.PCTUploaded, 'Uploaded')
            xlabel(app.PCTUploaded, 'X')
            ylabel(app.PCTUploaded, 'Z')
            zlabel(app.PCTUploaded, 'Z')
            app.PCTComparison = uiaxes(app.GridLayout8);
            title(app.PCTComparison, 'Comparison')
            xlabel(app.PCTComparison, 'Y')
            ylabel(app.PCTComparison, 'Z')
            zlabel(app.PCTComparison, 'Z')
            app.UploadButton_2 = uibutton(app.GridLayout8, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.ClearcomparisonButton = uibutton(app.GridLayout8, 'push');
            app.ClearcomparisonButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcomparisonButtonPushed, true);
            app.ClearcomparisonButton.Text = 'Clear comparison';
            app.SelectProfileDropDownLabel = uilabel(app.GridLayout8);
            app.SelectProfileDropDownLabel.Text = 'Select Profile';
            app.PCTDropDown = uidropdown(app.GridLayout8);
            app.PCTDropDown.ValueChangedFcn = createCallbackFcn(app, @PCTDropDownValueChanged, true);
            app.Hyperlink = uihyperlink(app.GridLayout8);
            app.Hyperlink.URL = 'http://www.airfoiltools.com/plotter/index';
            app.Hyperlink.Text = ['Generate an airfoil' newline 'http://www.airfoiltools.com/plotter'];
            app.HoldcomparisonSwitchLabel = uilabel(app.GridLayout8);
            app.HoldcomparisonSwitchLabel.Text = 'Hold comparison';
            app.HoldcomparisonSwitch = uiswitch(app.GridLayout8, 'slider');
            app.HoldcomparisonSwitch.ValueChangedFcn = createCallbackFcn(app, @HoldcomparisonSwitchValueChanged, true);
            app.PlotcurrentButton = uibutton(app.GridLayout8, 'push');
            app.PlotcurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcurrentButtonPushed, true);
            app.PlotcurrentButton.Text = 'Plot current';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatabaseAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end