classdef DatabaseBuilding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeometryExtractionTab           matlab.ui.container.Tab
        RunningLamp                     matlab.ui.control.Lamp
        RunningLabel                    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        rc                              matlab.ui.control.EditField
        RadiuscutoffLabel               matlab.ui.control.Label
        tc                              matlab.ui.control.EditField
        ProfiletrailcuttingLabel        matlab.ui.control.Label
        DeltaField                      matlab.ui.control.EditField
        SelectGeometryLabel             matlab.ui.control.Label
        GeometryselectDropDown          matlab.ui.control.DropDown
        StepsField                      matlab.ui.control.EditField
        SamplingDeltaLabel              matlab.ui.control.Label
        ExtractionStepsLabel            matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ParametersandsettingsTab        matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        DatabasefillersettingsPanel     matlab.ui.container.Panel
        GridLayout4_6                   matlab.ui.container.GridLayout
        TheminimumnumberofvaluestheinterpolationLabel_2  matlab.ui.control.Label
        TresholdEditField               matlab.ui.control.EditField
        TresholdEditFieldLabel          matlab.ui.control.Label
        TheminimumnumberofvaluestheinterpolationLabel  matlab.ui.control.Label
        RemovingtresholdEditField       matlab.ui.control.EditField
        RemovingtresholdEditFieldLabel  matlab.ui.control.Label
        DatabasesettingsPanel_2         matlab.ui.container.Panel
        GridLayout4_5                   matlab.ui.container.GridLayout
        XfoilDebuglogsSwitch            matlab.ui.control.Switch
        XfoilDebuglogsSwitchLabel       matlab.ui.control.Label
        XfoilNcritEditField             matlab.ui.control.EditField
        XfoilNcritEditFieldLabel        matlab.ui.control.Label
        XfoilNiterEditField             matlab.ui.control.EditField
        XfoilNiterEditFieldLabel        matlab.ui.control.Label
        DatabasedensitySlider           matlab.ui.control.Slider
        DatabasedensitySliderLabel      matlab.ui.control.Label
        XfoilkilltimesSlider            matlab.ui.control.Slider
        XfoilkilltimesSliderLabel       matlab.ui.control.Label
        ParametersandconstantsPanel     matlab.ui.container.Panel
        GridLayout4_3                   matlab.ui.container.GridLayout
        LenghtunitconversionfactorfromxtometersEditField  matlab.ui.control.EditField
        LenghtunitconversionfactorfromxtometersEditFieldLabel  matlab.ui.control.Label
        DynamicviscosityEditField       matlab.ui.control.EditField
        DynamicviscosityEditFieldLabel  matlab.ui.control.Label
        MinimumandmaximumRPMSSlider     matlab.ui.control.RangeSlider
        MinimumandmaximumRPMSSliderLabel  matlab.ui.control.Label
        textSoundspeedfractextmtextsEditField  matlab.ui.control.EditField
        textSpeedofsoundincurrentenvironmentmsLabel  matlab.ui.control.Label
        SavingoptionsPanel              matlab.ui.container.Panel
        GridLayout4_2                   matlab.ui.container.GridLayout
        LogsfilenameEditField           matlab.ui.control.EditField
        LogsfilenameEditFieldLabel      matlab.ui.control.Label
        DatafoldernameEditField         matlab.ui.control.EditField
        DatafoldernameEditFieldLabel    matlab.ui.control.Label
        ToggleModulesPanel              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DatabaseGeneratorSwitch         matlab.ui.control.Switch
        DatabaseGeneratorSwitchLabel    matlab.ui.control.Label
        ReplacesbrokenvaluesindatabasewithinterpolatedonesLabel  matlab.ui.control.Label
        UtilizesextractedprofilestocreateaDatabaseLabel  matlab.ui.control.Label
        Enables2DprofilesextracionfromaSTLfileLabel  matlab.ui.control.Label
        GeometryExtractionSwitch        matlab.ui.control.Switch
        GeometryExtractionSwitchLabel   matlab.ui.control.Label
        DatabaseFillerSwitch            matlab.ui.control.Switch
        DatabaseFillerSwitchLabel       matlab.ui.control.Label
        BuildingResultsTab              matlab.ui.container.Tab
        LogsArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Autom8Launched % Description
        Autom8Finished % Description
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)

        function UpdateLogs(app)
            LogsText= fileread("Logs\"+app.LogsfilenameEditField.Value+".txt");
            app.LogsArea.Value=LogsText;
        end

        function Result=OnToTrue(~,Value)
            if Value=="On"
                Result=true;
            else
                Result=false;
            end
        end
        
        %Update graph for live preview
        function Update_Graphic(app)
            
            %Data retrieving
            StlName = app.GeometryselectDropDown.Value;
            StlPath= "STL\"+StlName;
            [TR] = stlread(StlPath);
            Output=StlGeometryAutomaticFix(TR);
            XX=Output.Points;
            xx=XX(:,1);
            yy=XX(:,2);
            zz=XX(:,3);

            %Plot update
            ax=app.UIAxes;
            axis(ax,"equal")
            plot3(ax,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
            hold(ax,"on")
            plot3(ax,0,0,0,"o","Color","red","MarkerSize",10)
            hold(ax,"off")

            %Cutting planes plot
            xpmin=min(xx);
            xpmax=max(xx);
            ypmin=min(yy);
            ypmax=max(yy);
            [xp,yp] = meshgrid([xpmin xpmax],[ypmin ypmax]);

            Numplanes=str2double(app.StepsField.Value);
            Delta=str2double(app.DeltaField.Value);

            %Plotting cutting planes
            zmin=min(zz);
            zmax=max(zz);
            zmid=(zmax+zmin)/2;
            zetas=linspace(zmin,zmid,Numplanes);
            for z=zetas
                zp = 0.*xp + 0.*xp + z;
                hold(ax,"on")
                surf(ax,-xp,zp,yp,"FaceColor","yellow");
                hold(ax,"off")
            end

            %Plotting delta planes
            rad=zmax-zmid;
            distance=rad*(Delta/100);
            z=distance/2;
            zp1 = 0.*xp + 0.*xp + z;
            zp2 = 0.*xp + 0.*xp - z;
            hold(ax,"on")
            surf(ax,-xp,zp1,yp,"FaceColor","red");
            surf(ax,-xp,zp2,yp,"FaceColor","red");
            hold(ax,"off")
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.Autom8Launched=false;
            app.Autom8Finished=false;

            %Adding crucial directories to path
            addpath("Functions\","STL\")

            %Gettig avaliable stl geometries
            StlList=dir("STL\*.STL");
            StlList=struct2cell(StlList);
            app.GeometryselectDropDown.Items=StlList(1,:);

            %Printing smt on graphic so it isnt empty
            Update_Graphic(app)


        end

        % Clicked callback: GeometryselectDropDown
        function GeometryselectDropDownClicked(app, event)
            Update_Graphic(app)
        end

        % Value changed function: StepsField
        function StepsFieldValueChanged(app, event)
            Update_Graphic(app)
        end

        % Value changed function: DeltaField
        function DeltaFieldValueChanged(app, event)
            Update_Graphic(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            AppParameters.FileName=app.DatafoldernameEditField.Value;
            AppParameters.ExtractorBool=OnToTrue(app,app.GeometryExtractionSwitch.Value);
            AppParameters.XfoilDbBool=OnToTrue(app,app.DatabaseGeneratorSwitch.Value);
            AppParameters.DbFillerBool=OnToTrue(app,app.DatabaseFillerSwitch.Value);
            AppParameters.StlPath=app.GeometryselectDropDown.Value;
            AppParameters.Steps=str2double(app.StepsField.Value);
            AppParameters.Delta=str2double(app.DeltaField.Value);
            AppParameters.cutoff=str2double(app.rc.Value);
            AppParameters.TrailCutPerc=str2double(app.tc.Value);

            %Page 2
            AppParameters.range_rpm=[app.MinimumandmaximumRPMSSlider.Value];
            AppParameters.Soundspeed=str2double(app.textSoundspeedfractextmtextsEditField.Value);
            AppParameters.ni=str2double(app.DynamicviscosityEditField.Value);
            AppParameters.uf=str2double(app.LenghtunitconversionfactorfromxtometersEditField.Value);
            AppParameters.dbxf=OnToTrue(app,app.XfoilDebuglogsSwitch.Value);
            AppParameters.killtime=round(app.XfoilkilltimesSlider.Value);
            AppParameters.dbdens=round(app.DatabasedensitySlider.Value);
            AppParameters.niter=round(str2double(app.XfoilNiterEditField.Value));
            AppParameters.ncrit=round(str2double(app.XfoilNcritEditField.Value));
            AppParameters.rmt=str2double(app.RemovingtresholdEditField.Value);
            AppParameters.perct=str2double(app.TresholdEditField.Value);
            AppParameters.LogsFileName=app.LogsfilenameEditField.Value;

            %Launch
            app.Autom8Launched=true;
            app.RunningLamp.Color=([0,1,0]);
            app.LogsArea.Value="Please wait for execution to complete to view logs.";

            Autom8(AppParameters)

            app.Autom8Finished=true;
            app.RunningLamp.Color=([1,1,1]);
            UpdateLogs(app)
            
            %Reset couple
            app.Autom8Finished=false;
            app.Autom8Launched=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometryExtractionTab = uitab(app.TabGroup);
            app.GeometryExtractionTab.Title = 'Geometry Extraction';
            app.UIAxes = uiaxes(app.GeometryExtractionTab);
            title(app.UIAxes, 'Geometry preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.GeometryExtractionTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Panel = uipanel(app.GeometryExtractionTab);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ExtractionStepsLabel = uilabel(app.GridLayout2);
            app.ExtractionStepsLabel.Text = 'Extraction Steps';
            app.SamplingDeltaLabel = uilabel(app.GridLayout2);
            app.SamplingDeltaLabel.Text = 'Sampling Delta %';
            app.StepsField = uieditfield(app.GridLayout2, 'text');
            app.StepsField.ValueChangedFcn = createCallbackFcn(app, @StepsFieldValueChanged, true);
            app.StepsField.Value = '10';
            app.GeometryselectDropDown = uidropdown(app.GridLayout2);
            app.GeometryselectDropDown.ClickedFcn = createCallbackFcn(app, @GeometryselectDropDownClicked, true);
            app.SelectGeometryLabel = uilabel(app.GridLayout2);
            app.SelectGeometryLabel.Text = 'Select Geometry';
            app.DeltaField = uieditfield(app.GridLayout2, 'text');
            app.DeltaField.ValueChangedFcn = createCallbackFcn(app, @DeltaFieldValueChanged, true);
            app.DeltaField.Value = '2';
            app.ProfiletrailcuttingLabel = uilabel(app.GridLayout2);
            app.ProfiletrailcuttingLabel.Text = 'Profile trail cutting %';
            app.tc = uieditfield(app.GridLayout2, 'text');
            app.tc.Value = '99';
            app.RadiuscutoffLabel = uilabel(app.GridLayout2);
            app.RadiuscutoffLabel.Text = 'Radius cutoff %';
            app.rc = uieditfield(app.GridLayout2, 'text');
            app.rc.Value = '0.1';
            app.RunningLabel = uilabel(app.GeometryExtractionTab);
            app.RunningLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.GeometryExtractionTab);
            app.ParametersandsettingsTab = uitab(app.TabGroup);
            app.ParametersandsettingsTab.Title = 'Parameters and settings';
            app.GridLayout3 = uigridlayout(app.ParametersandsettingsTab);
            app.ToggleModulesPanel = uipanel(app.GridLayout3);
            app.ToggleModulesPanel.Title = 'Toggle Modules - For debuging purposes. Leave all to On unless you know what you are doing.';
            app.GridLayout4 = uigridlayout(app.ToggleModulesPanel);
            app.DatabaseFillerSwitchLabel = uilabel(app.GridLayout4);
            app.DatabaseFillerSwitchLabel.Text = 'Database Filler';
            app.DatabaseFillerSwitch = uiswitch(app.GridLayout4, 'slider');
            app.DatabaseFillerSwitch.Value = 'On';
            app.GeometryExtractionSwitchLabel = uilabel(app.GridLayout4);
            app.GeometryExtractionSwitchLabel.Text = 'Geometry Extraction';
            app.GeometryExtractionSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GeometryExtractionSwitch.Value = 'On';
            app.Enables2DprofilesextracionfromaSTLfileLabel = uilabel(app.GridLayout4);
            app.Enables2DprofilesextracionfromaSTLfileLabel.Text = 'Enables 2D profiles extracion from a STL file';
            app.UtilizesextractedprofilestocreateaDatabaseLabel = uilabel(app.GridLayout4);
            app.UtilizesextractedprofilestocreateaDatabaseLabel.Text = 'Utilizes extracted profiles to create a Database';
            app.ReplacesbrokenvaluesindatabasewithinterpolatedonesLabel = uilabel(app.GridLayout4);
            app.ReplacesbrokenvaluesindatabasewithinterpolatedonesLabel.Text = 'Replaces broken values in database with interpolated ones.';
            app.DatabaseGeneratorSwitchLabel = uilabel(app.GridLayout4);
            app.DatabaseGeneratorSwitchLabel.Text = 'Database Generator';
            app.DatabaseGeneratorSwitch = uiswitch(app.GridLayout4, 'slider');
            app.DatabaseGeneratorSwitch.Value = 'On';
            app.SavingoptionsPanel = uipanel(app.GridLayout3);
            app.SavingoptionsPanel.Title = 'Saving options';
            app.GridLayout4_2 = uigridlayout(app.SavingoptionsPanel);
            app.DatafoldernameEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DatafoldernameEditFieldLabel.Text = 'Data folder name';
            app.DatafoldernameEditField = uieditfield(app.GridLayout4_2, 'text');
            app.DatafoldernameEditField.Value = 'NewDatabase';
            app.LogsfilenameEditFieldLabel = uilabel(app.GridLayout4_2);
            app.LogsfilenameEditFieldLabel.Text = 'Logs file name';
            app.LogsfilenameEditField = uieditfield(app.GridLayout4_2, 'text');
            app.LogsfilenameEditField.Value = 'Lastest';
            app.ParametersandconstantsPanel = uipanel(app.GridLayout3);
            app.ParametersandconstantsPanel.Title = 'Parameters and constants';
            app.GridLayout4_3 = uigridlayout(app.ParametersandconstantsPanel);
            app.textSpeedofsoundincurrentenvironmentmsLabel = uilabel(app.GridLayout4_3);
            app.textSpeedofsoundincurrentenvironmentmsLabel.Interpreter = 'latex';
            app.textSpeedofsoundincurrentenvironmentmsLabel.Text = '\text Sound speed \frac {\text [m]}{\text [s]}';
            app.textSoundspeedfractextmtextsEditField = uieditfield(app.GridLayout4_3, 'text');
            app.textSoundspeedfractextmtextsEditField.Value = '330';
            app.MinimumandmaximumRPMSSliderLabel = uilabel(app.GridLayout4_3);
            app.MinimumandmaximumRPMSSliderLabel.Text = 'Minimum and maximum RPMS';
            app.MinimumandmaximumRPMSSlider = uislider(app.GridLayout4_3, 'range');
            app.MinimumandmaximumRPMSSlider.Limits = [0 10000];
            app.MinimumandmaximumRPMSSlider.Value = [2300 3200];
            app.DynamicviscosityEditFieldLabel = uilabel(app.GridLayout4_3);
            app.DynamicviscosityEditFieldLabel.Interpreter = 'latex';
            app.DynamicviscosityEditFieldLabel.Text = '\text Dynamic viscosity \frac{\text{[Kg]}}{\text{[s]} \text{[m]}}';
            app.DynamicviscosityEditField = uieditfield(app.GridLayout4_3, 'text');
            app.DynamicviscosityEditField.Value = '1.5e-5';
            app.LenghtunitconversionfactorfromxtometersEditFieldLabel = uilabel(app.GridLayout4_3);
            app.LenghtunitconversionfactorfromxtometersEditFieldLabel.Text = 'Lenght unit conversion factor (from x to meters)';
            app.LenghtunitconversionfactorfromxtometersEditField = uieditfield(app.GridLayout4_3, 'text');
            app.LenghtunitconversionfactorfromxtometersEditField.Value = '1e-3';
            app.DatabasesettingsPanel_2 = uipanel(app.GridLayout3);
            app.DatabasesettingsPanel_2.Title = 'Database settings';
            app.GridLayout4_5 = uigridlayout(app.DatabasesettingsPanel_2);
            app.XfoilkilltimesSliderLabel = uilabel(app.GridLayout4_5);
            app.XfoilkilltimesSliderLabel.Text = 'Xfoil killtime [s]';
            app.XfoilkilltimesSlider = uislider(app.GridLayout4_5);
            app.XfoilkilltimesSlider.Limits = [1 10];
            app.XfoilkilltimesSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.XfoilkilltimesSlider.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
            app.XfoilkilltimesSlider.Value = 4;
            app.DatabasedensitySliderLabel = uilabel(app.GridLayout4_5);
            app.DatabasedensitySliderLabel.Text = 'Database density';
            app.DatabasedensitySlider = uislider(app.GridLayout4_5);
            app.DatabasedensitySlider.Limits = [4 20];
            app.DatabasedensitySlider.MajorTicks = [4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.DatabasedensitySlider.MinorTicks = [4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10 10.2 10.4 10.6 10.8 11 11.2 11.4 11.6 11.8 12 12.2 12.4 12.6 12.8 13 13.2 13.4 13.6 13.8 14 14.2 14.4 14.6 14.8 15 15.2 15.4 15.6 15.8 16 16.2 16.4 16.6 16.8 17 17.2 17.4 17.6 17.8 18 18.2 18.4 18.6 18.8 19 19.2 19.4 19.6 19.8 20];
            app.DatabasedensitySlider.Value = 5;
            app.XfoilNiterEditFieldLabel = uilabel(app.GridLayout4_5);
            app.XfoilNiterEditFieldLabel.Text = 'Xfoil N-iter';
            app.XfoilNiterEditField = uieditfield(app.GridLayout4_5, 'text');
            app.XfoilNiterEditField.Value = '300';
            app.XfoilNcritEditFieldLabel = uilabel(app.GridLayout4_5);
            app.XfoilNcritEditFieldLabel.Text = 'Xfoil N-crit';
            app.XfoilNcritEditField = uieditfield(app.GridLayout4_5, 'text');
            app.XfoilNcritEditField.Value = '9';
            app.XfoilDebuglogsSwitchLabel = uilabel(app.GridLayout4_5);
            app.XfoilDebuglogsSwitchLabel.Text = 'Xfoil Debug logs';
            app.XfoilDebuglogsSwitch = uiswitch(app.GridLayout4_5, 'slider');
            app.DatabasefillersettingsPanel = uipanel(app.GridLayout3);
            app.DatabasefillersettingsPanel.Title = 'Database filler settings';
            app.GridLayout4_6 = uigridlayout(app.DatabasefillersettingsPanel);
            app.RemovingtresholdEditFieldLabel = uilabel(app.GridLayout4_6);
            app.RemovingtresholdEditFieldLabel.Text = 'Removing treshold';
            app.RemovingtresholdEditField = uieditfield(app.GridLayout4_6, 'text');
            app.RemovingtresholdEditField.Value = '2';
            app.TheminimumnumberofvaluestheinterpolationLabel = uilabel(app.GridLayout4_6);
            app.TheminimumnumberofvaluestheinterpolationLabel.Text = 'The minimum number of values that is accepted to interpolate a database row';
            app.TresholdEditFieldLabel = uilabel(app.GridLayout4_6);
            app.TresholdEditFieldLabel.Text = 'Treshold %';
            app.TresholdEditField = uieditfield(app.GridLayout4_6, 'text');
            app.TresholdEditField.Value = '100';
            app.TheminimumnumberofvaluestheinterpolationLabel_2 = uilabel(app.GridLayout4_6);
            app.TheminimumnumberofvaluestheinterpolationLabel_2.Text = 'Additional check. If in a row the non-valid numbers percentage is greather than this, that row is skipped. 100 to disable.';
            app.BuildingResultsTab = uitab(app.TabGroup);
            app.BuildingResultsTab.Title = 'Building Results';
            app.LogsArea = uitextarea(app.BuildingResultsTab);
            app.LogsArea.Value = {'Wait for extraction end to view logs.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatabaseBuilding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end