classdef Autom8App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ExtractionSettingsTab        matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GeometryselectDropDown       matlab.ui.control.DropDown
        GeometryselectDropDownLabel  matlab.ui.control.Label
        StepsField                   matlab.ui.control.EditField
        DeltaField                   matlab.ui.control.EditField
        DGSwitch                     matlab.ui.control.Switch
        HGSwitch                     matlab.ui.control.Switch
        GifSwitch                    matlab.ui.control.Switch
        SamplingDeltaLabel           matlab.ui.control.Label
        ExtractionStepsLabel         matlab.ui.control.Label
        DebugGraphsLabel             matlab.ui.control.Label
        HoldGraphsLabel              matlab.ui.control.Label
        GenerateGifLabel             matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        XfoilSettingsTab             matlab.ui.container.Tab
        ConstantsandParametersTab    matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
    end

    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        %Update graph for live preview
        function Update_Graphic(app)
            
            %Data retrieving
            StlName = app.GeometryselectDropDown.Value;
            StlPath= "STL\"+StlName;
            [TR] = stlread(StlPath);
            Output=StlGeometryAutomaticFix(TR);
            XX=Output.Points;
            xx=XX(:,1);
            yy=XX(:,2);
            zz=XX(:,3);

            %Plot update
            ax=app.UIAxes;
            axis(ax,"equal")
            plot3(ax,-xx,zz,yy,"."); %Perfect rotation: -xx.zz.yy
            hold(ax,"on")
            plot3(ax,0,0,0,"o","Color","red","MarkerSize",10)
            hold(ax,"off")

            %Cutting planes plot
            xpmin=min(xx);
            xpmax=max(xx);
            ypmin=min(yy);
            ypmax=max(yy);
            [xp,yp] = meshgrid([xpmin xpmax],[ypmin ypmax]);

            Numplanes=str2double(app.StepsField.Value);
            Delta=str2double(app.DeltaField.Value);

            %Plotting cutting planes
            zmin=min(zz);
            zmax=max(zz);
            zetas=linspace(zmin,zmax,Numplanes);
            for z=zetas
                zp = 0.*xp + 0.*xp + z;
                hold(ax,"on")
                surf(ax,-xp,zp,yp,"FaceColor","yellow");
                hold(ax,"off")
            end

            %Plotting delta planes
            rad=zmax-zmin;
            distance=rad*(Delta/100);
            z=distance/2;
            zp1 = 0.*xp + 0.*xp + z;
            zp2 = 0.*xp + 0.*xp - z;
            hold(ax,"on")
            surf(ax,-xp,zp1,yp,"FaceColor","red");
            surf(ax,-xp,zp2,yp,"FaceColor","red");
            hold(ax,"off")
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Adding crucial directories to path
            addpath("Functions\","STL\")

            %Gettig avaliable stl geometries
            StlList=dir("STL\*.STL");
            StlList=struct2cell(StlList);
            app.GeometryselectDropDown.Items=StlList(1,:);

            %Printing smt on graphic so it isnt empty
            Update_Graphic(app)

        end

        % Clicked callback: GeometryselectDropDown
        function GeometryselectDropDownClicked(app, event)
            Update_Graphic(app)
        end

        % Value changed function: StepsField
        function StepsFieldValueChanged(app, event)
            Update_Graphic(app)
        end

        % Value changed function: DeltaField
        function DeltaFieldValueChanged(app, event)
            Update_Graphic(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExtractionSettingsTab = uitab(app.TabGroup);
            app.ExtractionSettingsTab.Title = 'Extraction Settings';
            app.UIAxes = uiaxes(app.ExtractionSettingsTab);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.ExtractionSettingsTab, 'push');
            app.RunButton.Text = 'Run';
            app.Panel = uipanel(app.ExtractionSettingsTab);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GenerateGifLabel = uilabel(app.GridLayout2);
            app.GenerateGifLabel.Text = 'Generate Gif';
            app.HoldGraphsLabel = uilabel(app.GridLayout2);
            app.HoldGraphsLabel.Text = 'Hold Graphs';
            app.DebugGraphsLabel = uilabel(app.GridLayout2);
            app.DebugGraphsLabel.Text = 'Debug Graphs';
            app.ExtractionStepsLabel = uilabel(app.GridLayout2);
            app.ExtractionStepsLabel.Text = 'Extraction Steps';
            app.SamplingDeltaLabel = uilabel(app.GridLayout2);
            app.SamplingDeltaLabel.Text = 'Sampling Delta %';
            app.GifSwitch = uiswitch(app.GridLayout2, 'slider');
            app.HGSwitch = uiswitch(app.GridLayout2, 'slider');
            app.DGSwitch = uiswitch(app.GridLayout2, 'slider');
            app.DeltaField = uieditfield(app.GridLayout2, 'text');
            app.DeltaField.ValueChangedFcn = createCallbackFcn(app, @DeltaFieldValueChanged, true);
            app.DeltaField.Value = '2';
            app.StepsField = uieditfield(app.GridLayout2, 'text');
            app.StepsField.ValueChangedFcn = createCallbackFcn(app, @StepsFieldValueChanged, true);
            app.StepsField.Value = '10';
            app.GeometryselectDropDownLabel = uilabel(app.GridLayout2);
            app.GeometryselectDropDownLabel.Text = 'Geometry select';
            app.GeometryselectDropDown = uidropdown(app.GridLayout2);
            app.GeometryselectDropDown.ClickedFcn = createCallbackFcn(app, @GeometryselectDropDownClicked, true);
            app.XfoilSettingsTab = uitab(app.TabGroup);
            app.XfoilSettingsTab.Title = 'Xfoil Settings';
            app.ConstantsandParametersTab = uitab(app.TabGroup);
            app.ConstantsandParametersTab.Title = 'Constants and Parameters';
            app.GridLayout = uigridlayout(app.ConstantsandParametersTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autom8App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end